classdef GUI_Morze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TextArea_2Label     matlab.ui.control.Label
        TextArea_2          matlab.ui.control.TextArea
        Panel               matlab.ui.container.Panel
        PortDropDownLabel   matlab.ui.control.Label
        Port_List_Fnc       matlab.ui.control.DropDown
        BandRateKnobLabel   matlab.ui.control.Label
        Baud_Rate           matlab.ui.control.DiscreteKnob
        TransmitLampLabel   matlab.ui.control.Label
        Transmit_Lamp       matlab.ui.control.Lamp
        ReceiveLampLabel    matlab.ui.control.Label
        Receive_Lamp        matlab.ui.control.Lamp
        Scale_Power         matlab.ui.control.NinetyDegreeGauge
        ConnectSwitchLabel  matlab.ui.control.Label
        Connect_Switch      matlab.ui.control.Switch
        Label_2             matlab.ui.control.Label
        EditField           matlab.ui.control.EditField
    end


    properties (Access = private)
        PORT % Serial port
        TIMER % Timer upgrade
        MIN_RANGE % Scale_Power
        MAX_RANGE % Scale_Power
        ON_OFF          = 'Off';% Description
        
        
    end

    methods (Access = private)
    
        function results= Rand_Function(app) 
            RAND_MAX    =    2147483647;
            results     =    double(...
                app.MIN_RANGE + mod(rand(), RAND_MAX) * ...
                                (app.MAX_RANGE - app.MIN_RANGE));
        end
        
        function Interface_Upgrade(app, ~)
            app.Scale_Power.Value        = Rand_Function(app);
            
            if length(app.ON_OFF) ~= length(app.Connect_Switch.Value)
                
                app.ON_OFF = app.Connect_Switch.Value;
                
                if (length(app.Connect_Switch.Value) == length('On'))
                
                    app.MAX_RANGE          = 25;
                    app.MIN_RANGE          = 15;
                    app.MAX_RANGE          = Rand_Function(app);
                    app.MIN_RANGE          = 15;
                    app.MIN_RANGE          = Rand_Function(app);
                    
                    app.PORT               = serial(app.Port_List_Fnc.Value);
                    app.PORT.BaudRate      = str2double(app.Baud_Rate.Value);
                    app.PORT.BytesAvailableFcnCount    ...
                                           = 6;
                    app.PORT.BytesAvailableFcnMode     ...
                                           = 'byte';
                    app.PORT.BytesAvailableFcn         ...
                                           = @(~,~)app.Read_Data;
                    fopen(app.PORT);
                else
                    app.MAX_RANGE          = 10;
                    app.MIN_RANGE          = 5;
                    app.MAX_RANGE          = Rand_Function(app);
                    app.MIN_RANGE          = 0;
                    app.MIN_RANGE          = Rand_Function(app);
    %                 stop(app.TIMER);
                    stopasync(app.PORT);
                    fclose(app.PORT);
                end
            end
        end
        
        function Read_Data(app)
            
        end
        
        function Connect_Port(app)
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %%    Закрытие порта, на случай непредвиденных обстоятельств
            close all
            delete(instrfindall);
            
            
            Port_List = instrhwinfo('serial');
            
            app.Port_List_Fnc.Items = Port_List.AvailableSerialPorts;
            app.PORT               = serial(app.Port_List_Fnc.Value);
            app.PORT               = {num2str(app.PORT.BaudRate)};
            app.Baud_Rate.Value = app.PORT;
            
            app.MAX_RANGE          = 10;
            app.MIN_RANGE          = 5;
            app.MAX_RANGE          = Rand_Function(app);
            app.MIN_RANGE          = 0;
            app.MIN_RANGE          = Rand_Function(app);
            
%             %    Timer
            app.TIMER              = timer;
            app.TIMER.TimerFcn     = @(~,~)app.Interface_Upgrade;
            app.TIMER.ExecutionMod = 'fixedSpacing';
            app.TIMER.Period       = 5e-3;
            app.TIMER.BusyMode     = 'drop';
            start(app.TIMER);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TextArea_2Label = uilabel(app.UIFigure);
            app.TextArea_2Label.Text = 'Текст по морзянке (принятый)';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Управление';
            app.PortDropDownLabel = uilabel(app.Panel);
            app.PortDropDownLabel.Text = 'Port';
            app.Port_List_Fnc = uidropdown(app.Panel);
            app.Port_List_Fnc.Items = {};
            app.Port_List_Fnc.Editable = 'on';
            app.Port_List_Fnc.Value = {};
            app.BandRateKnobLabel = uilabel(app.Panel);
            app.BandRateKnobLabel.Text = 'Band Rate';
            app.Baud_Rate = uiknob(app.Panel, 'discrete');
            app.Baud_Rate.Items = {'4800', '9600', '19200', '38400', '57600', '115200'};
            app.Baud_Rate.Value = '4800';
            app.TransmitLampLabel = uilabel(app.Panel);
            app.TransmitLampLabel.Text = 'Transmit';
            app.Transmit_Lamp = uilamp(app.Panel);
            app.ReceiveLampLabel = uilabel(app.Panel);
            app.ReceiveLampLabel.Text = 'Receive';
            app.Receive_Lamp = uilamp(app.Panel);
            app.Scale_Power = uigauge(app.Panel, 'ninetydegree');
            app.Scale_Power.MajorTicks = [0 25 50 75 100];
            app.Scale_Power.ScaleDirection = 'counterclockwise';
            app.ConnectSwitchLabel = uilabel(app.Panel);
            app.ConnectSwitchLabel.Text = 'Connect';
            app.Connect_Switch = uiswitch(app.Panel, 'slider');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Текст по морзянке (отправленный)';
            app.EditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_Morze()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end